classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        GridLayout5                    matlab.ui.container.GridLayout
        ButtonGroup                    matlab.ui.container.ButtonGroup
        PaddingButton                  matlab.ui.control.RadioButton
        BordersButton                  matlab.ui.control.RadioButton
        GridLayout17                   matlab.ui.container.GridLayout
        ExtrapolationmethodDropDown    matlab.ui.control.DropDown
        ExtrapolationmethodDropDownLabel  matlab.ui.control.Label
        GridLayout16                   matlab.ui.container.GridLayout
        InterpolationmethodDropDown    matlab.ui.control.DropDown
        InterpolationmethodDropDownLabel  matlab.ui.control.Label
        GridLayout15                   matlab.ui.container.GridLayout
        EditField_12                   matlab.ui.control.NumericEditField
        ContourlayeropacityLabel       matlab.ui.control.Label
        GridLayout14                   matlab.ui.container.GridLayout
        EditField_9                    matlab.ui.control.NumericEditField
        EditField_8                    matlab.ui.control.NumericEditField
        EditField_7                    matlab.ui.control.NumericEditField
        EditField_6                    matlab.ui.control.NumericEditField
        GridLayout13                   matlab.ui.container.GridLayout
        EditField_10                   matlab.ui.control.NumericEditField
        EditField_4                    matlab.ui.control.NumericEditField
        Label_3                        matlab.ui.control.Label
        DatarangeLabel                 matlab.ui.control.Label
        GridLayout12                   matlab.ui.container.GridLayout
        EditField_11                   matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        MaxresolutionLabel             matlab.ui.control.Label
        GridLayout11                   matlab.ui.container.GridLayout
        InterpolatetimestepsindataLabel  matlab.ui.control.Label
        CheckBox_2                     matlab.ui.control.CheckBox
        GridLayout10                   matlab.ui.container.GridLayout
        EnableparallelizationLabel     matlab.ui.control.Label
        CheckBox                       matlab.ui.control.CheckBox
        GridLayout9                    matlab.ui.container.GridLayout
        Button_4                       matlab.ui.control.Button
        OutputfileEditField            matlab.ui.control.EditField
        OutputfileEditFieldLabel       matlab.ui.control.Label
        GridLayout8                    matlab.ui.container.GridLayout
        Button_3                       matlab.ui.control.Button
        MapfileEditField               matlab.ui.control.EditField
        MapfileEditFieldLabel          matlab.ui.control.Label
        GridLayout7                    matlab.ui.container.GridLayout
        Button_2                       matlab.ui.control.Button
        VisualizationdataEditField     matlab.ui.control.EditField
        VisualizationdataEditFieldLabel  matlab.ui.control.Label
        GridLayout6                    matlab.ui.container.GridLayout
        CoordinatesdataEditField       matlab.ui.control.EditField
        CoordinatesdataEditFieldLabel  matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        GridLayout4                    matlab.ui.container.GridLayout
        RenderButton                   matlab.ui.control.Button
        PreviewButton                  matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        Image4                         matlab.ui.control.Image
        Image3                         matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        renderer VideoRenderer
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.renderer = VideoRenderer;

            app.EditField_10.Value = app.renderer.data_min;
            app.EditField_4.Value = app.renderer.data_max;
            app.EditField_12.Value = app.renderer.opacity;

            if strcmp(app.renderer.interpolate_method, 'linear')
                app.InterpolationmethodDropDown.Value = 'Linear';
            elseif strcmp(app.renderer.interpolate_method, 'nearest')
                app.InterpolationmethodDropDown.Value = 'Nearest Neighbor';
            elseif strcmp(app.renderer.interpolate_method, 'natural')
                app.InterpolationmethodDropDown.Value = 'Natural Neighbor';
            end

            if strcmp(app.renderer.extrapolate_method, 'none')
                app.ExtrapolationmethodDropDown.Value = 'None';
            elseif strcmp(app.renderer.extrapolate_method, 'linear')
                app.ExtrapolationmethodDropDown.Value = 'Linear';
            elseif strcmp(app.renderer.extrapolate_method, 'nearest')
                app.ExtrapolationmethodDropDown.Value = 'Nearest Neighbor';
            end

            app.CheckBox_2.Value = app.renderer.interpolate_timestamps;
            app.EditField_11.Value = app.renderer.max_resolution(1);
            app.EditField_2.Value = app.renderer.max_resolution(2);

            if ~isempty(app.renderer.borders)
                app.BordersButton.Value = true;

                app.EditField_6.Value = app.renderer.borders(1);
                app.EditField_7.Value = app.renderer.borders(2);
                app.EditField_8.Value = app.renderer.borders(3);
                app.EditField_9.Value = app.renderer.borders(4);
            elseif ~isempty(app.renderer.padding)
                app.PaddingButton.Value = true;

                app.EditField_6.Value = app.renderer.padding(1);
                app.EditField_7.Value = app.renderer.padding(2);
                app.EditField_8.Value = app.renderer.padding(3);
                app.EditField_9.Value = app.renderer.padding(4);
            end

            app.CheckBox.Value = app.renderer.parallelize;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file, path] = uigetfile({'*.txt;*.dat;*.csv;*.xls;*.xlsx;*.ods'; '*.*'});
            
            if ~isequal(file, 0)
                app.CoordinatesdataEditField.Value = [path file];
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [file, path] = uigetfile({'*.txt;*.dat;*.csv;*.xls;*.xlsx;*.ods'; '*.*'});
            
            if ~isequal(file, 0)
                app.VisualizationdataEditField.Value = [path file];
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            [file, path] = uigetfile({'*.bmp;*.jpg;*.jpeg;*.pbm;*;pgm;*.ppm;*.png;*.tif;*.tiff'; '*.*'});
            
            if ~isequal(file, 0)
                app.MapfileEditField.Value = [path file];
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            [file, path] = uiputfile({'*.avi'; '*.mp4'; '*.mj2'; '*.*'});
            
            if ~isequal(file, 0)
                app.OutputfileEditField.Value = [path file];
            end
        end

        % Button pushed function: RenderButton
        function RenderButtonPushed(app, event)
            app.renderer.coords_file = app.CoordinatesdataEditField.Value;
            app.renderer.visdata_file = app.VisualizationdataEditField.Value;
            app.renderer.map_file = app.MapfileEditField.Value;
            
            app.renderer.data_min = app.EditField_10.Value;
            app.renderer.data_max = app.EditField_4.Value;
            app.renderer.opacity = app.EditField_12.Value;

            if strcmp(app.InterpolationmethodDropDown.Value, 'Linear')
                app.renderer.interpolate_method = 'linear';
            elseif strcmp(app.InterpolationmethodDropDown.Value, 'Nearest Neighbor')
                app.renderer.interpolate_method = 'nearest';
            elseif strcmp(app.InterpolationmethodDropDown.Value, 'Natural Neighbor')
                app.renderer.interpolate_method = 'natural';
            end

            if strcmp(app.ExtrapolationmethodDropDown.Value, 'None')
                app.renderer.extrapolate_method = 'none';
            elseif strcmp(app.ExtrapolationmethodDropDown.Value, 'Linear')
                app.renderer.extrapolate_method = 'linear';
            elseif strcmp(app.ExtrapolationmethodDropDown.Value, 'Nearest Neighbor')
                app.renderer.extrapolate_method = 'nearest';
            end

            app.renderer.interpolate_timestamps = app.CheckBox_2.Value;
            app.renderer.max_resolution = [app.EditField_11.Value app.EditField_2.Value];

            if app.BordersButton.Value
                app.renderer.borders = [app.EditField_6.Value app.EditField_7.Value app.EditField_8.Value app.EditField_9.Value];
                app.renderer.padding = [];
            elseif app.PaddingButton.Value
                app.renderer.padding = [app.EditField_6.Value app.EditField_7.Value app.EditField_8.Value app.EditField_9.Value];
                app.renderer.borders = [];
            end

            app.renderer.parallelize = app.CheckBox.Value;
            app.renderer.setup;

            genvideo(app.renderer, app.OutputfileEditField.Value, true);
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            app.renderer.coords_file = app.CoordinatesdataEditField.Value;
            app.renderer.visdata_file = app.VisualizationdataEditField.Value;
            app.renderer.map_file = app.MapfileEditField.Value;
            
            app.renderer.data_min = app.EditField_10.Value;
            app.renderer.data_max = app.EditField_4.Value;
            app.renderer.opacity = app.EditField_12.Value;

            if strcmp(app.InterpolationmethodDropDown.Value, 'Linear')
                app.renderer.interpolate_method = 'linear';
            elseif strcmp(app.InterpolationmethodDropDown.Value, 'Nearest Neighbor')
                app.renderer.interpolate_method = 'nearest';
            elseif strcmp(app.InterpolationmethodDropDown.Value, 'Natural Neighbor')
                app.renderer.interpolate_method = 'natural';
            end

            if strcmp(app.ExtrapolationmethodDropDown.Value, 'None')
                app.renderer.extrapolate_method = 'none';
            elseif strcmp(app.ExtrapolationmethodDropDown.Value, 'Linear')
                app.renderer.extrapolate_method = 'linear';
            elseif strcmp(app.ExtrapolationmethodDropDown.Value, 'Nearest Neighbor')
                app.renderer.extrapolate_method = 'nearest';
            end

            app.renderer.interpolate_timestamps = app.CheckBox_2.Value;
            app.renderer.max_resolution = [app.EditField_11.Value app.EditField_2.Value];

            if app.BordersButton.Value
                app.renderer.borders = [app.EditField_6.Value app.EditField_7.Value app.EditField_8.Value app.EditField_9.Value];
                app.renderer.padding = [];
            elseif app.PaddingButton.Value
                app.renderer.padding = [app.EditField_6.Value app.EditField_7.Value app.EditField_8.Value app.EditField_9.Value];
                app.renderer.borders = [];
            end

            app.renderer.parallelize = app.CheckBox.Value;
            app.renderer.setup;

            app.Image.ImageSource = genframe(app.renderer, 1);
            app.Image2.ImageSource = genframe(app.renderer, floor(app.renderer.frames / 3));
            app.Image3.ImageSource = genframe(app.renderer, floor(2 * app.renderer.frames / 3));
            app.Image4.ImageSource = genframe(app.renderer, app.renderer.frames);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.PreviewButton = uibutton(app.GridLayout4, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.RenderButton = uibutton(app.GridLayout4, 'push');
            app.RenderButton.ButtonPushedFcn = createCallbackFcn(app, @RenderButtonPushed, true);
            app.RenderButton.Text = 'Render';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.Button = uibutton(app.GridLayout6, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.CoordinatesdataEditFieldLabel = uilabel(app.GridLayout6);
            app.CoordinatesdataEditFieldLabel.Text = 'Coordinates data';
            app.CoordinatesdataEditField = uieditfield(app.GridLayout6, 'text');
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.VisualizationdataEditFieldLabel = uilabel(app.GridLayout7);
            app.VisualizationdataEditFieldLabel.Text = 'Visualization data';
            app.VisualizationdataEditField = uieditfield(app.GridLayout7, 'text');
            app.Button_2 = uibutton(app.GridLayout7, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '...';
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.MapfileEditFieldLabel = uilabel(app.GridLayout8);
            app.MapfileEditFieldLabel.Text = 'Map file';
            app.MapfileEditField = uieditfield(app.GridLayout8, 'text');
            app.Button_3 = uibutton(app.GridLayout8, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '...';
            app.GridLayout9 = uigridlayout(app.GridLayout5);
            app.OutputfileEditFieldLabel = uilabel(app.GridLayout9);
            app.OutputfileEditFieldLabel.Text = 'Output file';
            app.OutputfileEditField = uieditfield(app.GridLayout9, 'text');
            app.Button_4 = uibutton(app.GridLayout9, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '...';
            app.GridLayout10 = uigridlayout(app.GridLayout5);
            app.CheckBox = uicheckbox(app.GridLayout10);
            app.CheckBox.Text = '';
            app.EnableparallelizationLabel = uilabel(app.GridLayout10);
            app.EnableparallelizationLabel.Text = 'Enable parallelization';
            app.GridLayout11 = uigridlayout(app.GridLayout5);
            app.CheckBox_2 = uicheckbox(app.GridLayout11);
            app.CheckBox_2.Text = '';
            app.InterpolatetimestepsindataLabel = uilabel(app.GridLayout11);
            app.InterpolatetimestepsindataLabel.Text = 'Interpolate time steps in data';
            app.GridLayout12 = uigridlayout(app.GridLayout5);
            app.MaxresolutionLabel = uilabel(app.GridLayout12);
            app.MaxresolutionLabel.Text = 'Max resolution';
            app.EditField_2 = uieditfield(app.GridLayout12, 'numeric');
            app.EditField_2.Limits = [0 Inf];
            app.EditField_2.RoundFractionalValues = 'on';
            app.EditField_2.ValueDisplayFormat = '%u';
            app.EditField_2.Value = 720;
            app.EditField_11 = uieditfield(app.GridLayout12, 'numeric');
            app.EditField_11.Limits = [0 Inf];
            app.EditField_11.RoundFractionalValues = 'on';
            app.EditField_11.ValueDisplayFormat = '%u';
            app.EditField_11.Value = 1280;
            app.GridLayout13 = uigridlayout(app.GridLayout5);
            app.DatarangeLabel = uilabel(app.GridLayout13);
            app.DatarangeLabel.Text = 'Data range';
            app.Label_3 = uilabel(app.GridLayout13);
            app.Label_3.Text = '';
            app.EditField_4 = uieditfield(app.GridLayout13, 'numeric');
            app.EditField_4.Value = 1;
            app.EditField_10 = uieditfield(app.GridLayout13, 'numeric');
            app.GridLayout14 = uigridlayout(app.GridLayout5);
            app.EditField_6 = uieditfield(app.GridLayout14, 'numeric');
            app.EditField_7 = uieditfield(app.GridLayout14, 'numeric');
            app.EditField_8 = uieditfield(app.GridLayout14, 'numeric');
            app.EditField_9 = uieditfield(app.GridLayout14, 'numeric');
            app.GridLayout15 = uigridlayout(app.GridLayout5);
            app.ContourlayeropacityLabel = uilabel(app.GridLayout15);
            app.ContourlayeropacityLabel.Text = 'Contour layer opacity';
            app.EditField_12 = uieditfield(app.GridLayout15, 'numeric');
            app.EditField_12.Limits = [0 1];
            app.EditField_12.Value = 1;
            app.GridLayout16 = uigridlayout(app.GridLayout5);
            app.InterpolationmethodDropDownLabel = uilabel(app.GridLayout16);
            app.InterpolationmethodDropDownLabel.Text = 'Interpolation method';
            app.InterpolationmethodDropDown = uidropdown(app.GridLayout16);
            app.InterpolationmethodDropDown.Items = {'Linear', 'Nearest Neighbor', 'Natural Neighbor'};
            app.InterpolationmethodDropDown.Value = 'Linear';
            app.GridLayout17 = uigridlayout(app.GridLayout5);
            app.ExtrapolationmethodDropDownLabel = uilabel(app.GridLayout17);
            app.ExtrapolationmethodDropDownLabel.Text = 'Extrapolation method';
            app.ExtrapolationmethodDropDown = uidropdown(app.GridLayout17);
            app.ExtrapolationmethodDropDown.Items = {'None', 'Linear', 'Nearest Neighbor'};
            app.ExtrapolationmethodDropDown.Value = 'None';
            app.ButtonGroup = uibuttongroup(app.GridLayout5);
            app.BordersButton = uiradiobutton(app.ButtonGroup);
            app.BordersButton.Text = {'Borders'; ''};
            app.BordersButton.Value = true;
            app.PaddingButton = uiradiobutton(app.ButtonGroup);
            app.PaddingButton.Text = 'Padding';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.Image = uiimage(app.GridLayout2);
            app.Image2 = uiimage(app.GridLayout2);
            app.Image3 = uiimage(app.GridLayout2);
            app.Image4 = uiimage(app.GridLayout2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end